classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EnterLabel                     matlab.ui.control.Label
        EnterLabel_2                   matlab.ui.control.Label
        EnterLabel_3                   matlab.ui.control.Label
        EnterLabel_4                   matlab.ui.control.Label
        MEditFieldLabel                matlab.ui.control.Label
        MEditField                     matlab.ui.control.NumericEditField
        NfortimemeanEditFieldLabel     matlab.ui.control.Label
        NfortimemeanEditField          matlab.ui.control.NumericEditField
        iEditFieldLabel                matlab.ui.control.Label
        iEditField                     matlab.ui.control.NumericEditField
        jEditFieldLabel                matlab.ui.control.Label
        jEditField                     matlab.ui.control.NumericEditField
        PlotMsamplesButton             matlab.ui.control.Button
        CalcPlotensemblemeanButton     matlab.ui.control.Button
        CalctimemeanofNButton          matlab.ui.control.Button
        CalcACFofijButton              matlab.ui.control.Button
        CalctimeACFofNButton           matlab.ui.control.Button
        CalcPlotPSDButton              matlab.ui.control.Button
        CalctotalaveragepowerButton    matlab.ui.control.Button
        ProjectofPROBABILITYSTOCHASTICPROCESSESLabel  matlab.ui.control.Label
        NfortimeACFEditFieldLabel      matlab.ui.control.Label
        NfortimeACFEditField           matlab.ui.control.NumericEditField
        EnterLabel_5                   matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        PresstoimportXtButton          matlab.ui.control.Button
        PresstoimporttimevectorButton  matlab.ui.control.Button
        UITable2                       matlab.ui.control.Table
        UITable3                       matlab.ui.control.Table
        EditField                      matlab.ui.control.NumericEditField
        UITable4                       matlab.ui.control.Table
        EditField_2                    matlab.ui.control.NumericEditField
        UITable5                       matlab.ui.control.Table
        importXtCosfunctionwithitstimevectorButton  matlab.ui.control.Button
        PlotEnsembleACFButton          matlab.ui.control.Button
        EditField_3                    matlab.ui.control.NumericEditField
        EditField_4                    matlab.ui.control.NumericEditField
        TbEditFieldLabel               matlab.ui.control.Label
        TbEditField                    matlab.ui.control.NumericEditField
        EnterLabel_6                   matlab.ui.control.Label
        importXtofUNIPOLARCosfunctionwithitstimevectorButton  matlab.ui.control.Button
        noteimportthefunctionwitheachnewTbLabel  matlab.ui.control.Label
        importXtofPOLARCosfunctionwithitstimevectorButton  matlab.ui.control.Button
        importXtofManchesterCosfunctionwithitstimevectorButton  matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        pleasefillallneededvaluesbeforestartingLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        t %  represen X(t) but i used t to relate it with the time vector ,matrix to be shared among call backs
        time_vector % Description
        time_ACF % Description
        matrix % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)
            M = app.MEditField.Value;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: PresstoimportXtButton
        function PresstoimportXtButtonPushed(app, event)
            app.t = readmatrix('cos.xlsx');
            %t.s1(1) = 99;
            %t.s1(:) = mean(t.s1(:));
            app.UITable.Data = app.t;
        end

        % Button pushed function: PresstoimporttimevectorButton
        function PresstoimporttimevectorButtonPushed(app, event)
            %t2 = readtable('Book2.xlsx','sheet',2);
            %type matrix.txt
            app.time_vector = readmatrix('cos.xlsx','sheet',2);
            %preview('matrix.txt');
            %t2.s2(:) = 10;
            %app.UITable2.Data = t2;
             app.UITable2.Data = app.time_vector;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: CalcPlotensemblemeanButton
        function CalcPlotensemblemeanButtonPushed(app, event)
            Mean = mean(app.t);
            app.UITable3.Data = Mean;
            plot(app.time_vector, Mean);
            title('the ensemble mean of the process');
            xlabel('time vector');
            ylabel('ensemble mean');
            grid on;
        end

        % Cell edit callback: UITable3
        function UITable3CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: PlotMsamplesButton
        function PlotMsamplesButtonPushed(app, event)
            
            for C = 1 : app.MEditField.Value
                figure
                plot(app.time_vector,app.t(C,:));
                hold on
                title('one of the M samples of the process');
                xlabel('time vector');
                ylabel('#M sample');
                grid on;
            
            end
            
        end

        % Value changed function: NfortimemeanEditField
        function NfortimemeanEditFieldValueChanged(app, event)
            N = app.NfortimemeanEditField.Value;
            
        end

        % Value changed function: NfortimeACFEditField
        function NfortimeACFEditFieldValueChanged(app, event)
            value = app.NfortimeACFEditField.Value;
            
        end

        % Button pushed function: CalctimemeanofNButton
        function CalctimemeanofNButtonPushed(app, event)
            mean_nTHsample = mean(app.t(app.NfortimemeanEditField.Value,:));
            step = app.time_vector(2) - app.time_vector(1);
            result = (mean_nTHsample).*(step);
            app.EditField.Value = result;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Value changed function: iEditField
        function iEditFieldValueChanged(app, event)
            value = app.iEditField.Value;
            
        end

        % Button pushed function: CalcACFofijButton
        function CalcACFofijButtonPushed(app, event)
            %figure
            [count_rows,count_columns]=size(app.t);
            %count_columns = width(app.t);
            A = [];
                %s = 0;
            for i = 1 : count_rows
                 A(i,1) = (app.t(i,app.iEditField.Value)) .* (app.t(i,app.jEditField.Value));
                 %s = s + A(i,1);
                 %app.iEditField.Value;
             end 
            app.EditField_3.Value = mean(A(:,:));

           
        end

        % Value changed function: jEditField
        function jEditFieldValueChanged(app, event)
            value = app.jEditField.Value;
            
        end

        % Button pushed function: CalctimeACFofNButton
        function CalctimeACFofNButtonPushed(app, event)
                  %to get the selcted row 
            selcted_row = app.NfortimeACFEditField.Value;
            %[time_ACF,lags] = xcorr(app.t(app.NfortimeACFEditField.Value,:));
            app.time_ACF = xcorr(app.t(selcted_row,:),'biased');
            app.matrix = [];
            app.matrix = app.time_ACF(1000:end);
            app.EditField_2.Value = mean(app.matrix);
            plot(app.matrix);
            xlabel('time');
            ylabel('time ACF');
            title('Time ACF');
            %collection = 0;
            %[num_rows,num_columns]=size(selcted_row);
           % for tao = 1 : num_columns
            %    for j = 1 : num_columns - tao
             %      collection = collection + app.t(app.NfortimeACFEditField.Value,j) .* app.t(app.NfortimeACFEditField.Value,j + tao);
              %  end
            %end
            %step2 = app.t(app.NfortimeACFEditField.Value,2) .* app.t(app.NfortimeACFEditField.Value,1);
            %z = step2 ./ num_columns;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            
        end

        % Button pushed function: CalctotalaveragepowerButton
        function CalctotalaveragepowerButtonPushed(app, event)
            [count_rows count_columns] = size(app.t)
            totalpower = [];
            for i = 1: count_rows
              totalpower(1,i) = sum(app.t(i,:).*app.t(i,:));
            end 
            totalpower = mean(totalpower);
            %totalpower = sum(totalpower) /count_rows;
            app.EditField_4.Value = totalpower / 2;
        end

        % Button pushed function: CalcPlotPSDButton
        function CalcPlotPSDButtonPushed(app, event)
            figure
            PSD = [];
            dt = app.time_vector(1,2) - app.time_vector(1,1);
            df = 1 / (dt);
            [R C] = size(app.t);
            frq = linspace(-df/100,df/100,length(app.t));
            PSD = fft(app.matrix);
            plot(frq,PSD);
            title('power specral density')
            xlabel('frequency');
            ylabel('PSD');
        end

        % Cell edit callback: UITable5
        function UITable5CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
           
        end

        % Button pushed function: 
        % importXtCosfunctionwithitstimevectorButton
        function importXtCosfunctionwithitstimevectorButtonPushed(app, event)
            theta = unifrnd(-pi,pi,1000);
            omega = 2*pi;
            A = 5;
            app.time_vector = -5:0.01:5- 0.01;
            x=[];
            for i = 1:1000
            x(i,:) = A* cos(omega*app.time_vector + theta(i));
            end
            %figure(1)
            app.t = x(:,:);
            %plot(app.time_vector,app.t);
            %app.UITable.Data= app.t;
        end

        % Cell edit callback: UITable4
        function UITable4CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: PlotEnsembleACFButton
        function PlotEnsembleACFButtonPushed(app, event)
            result = 0;
            [count_rows count_columns] = size(app.t);
            ACF =[];
            for L = 1: count_columns
               for m = 1 : count_columns
                    for n = 1 : count_rows
                         result = result + ((app.t(n,L)) * (app.t(n,m))) ;
                    end 
                    ACF(L,m) = result/ count_rows; 
                    result = 0;     
                end
            end
            %disp(ACF);
            surf(ACF);
            xlabel('time');
            ylabel('time');
            zlabel('ACF');
            grid on
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            
        end

        % Button pushed function: 
        % importXtofUNIPOLARCosfunctionwithitstimevectorButton
        function importXtofUNIPOLARCosfunctionwithitstimevectorButtonPushed(app, event)
            Tb = app.TbEditField.Value;
            % u should alternate among 1, 10 and 100 for Tb
            A = 1;
            N = 100;
            alpha = randi([0,Tb],[1,N]);
            t = 0:0.1:N * Tb - 0.1;
            p = @(t) rectpuls(((t - (Tb / 2)) / Tb));
            x = [];
            bits = randi([0,1], [1,N]);
            for j = 1 : N
                %count_rows = height(t);
                x(j,:) = zeros(1,length(t));
                for  i = 1 : N
                   if (bits(i) == 0)
                       x(j,:) = x(j,:);
                   else
                       x(j,:) = x(j,:) + A.* p(t-i *Tb - alpha (j));
                   end
                end
            end
            app.t = x;
            app.time_vector = t;
        end

        % Value changed function: TbEditField
        function TbEditFieldValueChanged(app, event)
            value = app.TbEditField.Value;
            
        end

        % Button pushed function: 
        % importXtofPOLARCosfunctionwithitstimevectorButton
        function importXtofPOLARCosfunctionwithitstimevectorButtonPushed(app, event)
            Tb = app.TbEditField.Value;
            % u should alternate among 1, 10 and 100 for Tb
            A = 1;
            N = 100;
            alpha = randi([0,Tb],[1,N]);
            t = 0:0.1:N * Tb - 0.1;
            p = @(t) rectpuls(((t - (Tb / 2)) / Tb));
            x = [];
            bits = randi([0,1], [1,N]);
            for j = 1 : N
                %count_rows = height(t);
                x(j,:) = zeros(1,length(t));
                for  i = 1 : N
                   if (bits(i) == 0)
                       x(j,:) = x(j,:) - A.* p(t-i *Tb - alpha (j));
                   else
                       x(j,:) = x(j,:) + A.* p(t-i *Tb - alpha (j));
                   end
                end
            end
            app.t = x;
            app.time_vector = t;
        end

        % Button pushed function: 
        % importXtofManchesterCosfunctionwithitstimevectorButton
        function importXtofManchesterCosfunctionwithitstimevectorButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.EnterLabel = uilabel(app.UIFigure);
            app.EnterLabel.Text = 'Enter ';
            app.EnterLabel_2 = uilabel(app.UIFigure);
            app.EnterLabel_2.Text = 'Enter ';
            app.EnterLabel_3 = uilabel(app.UIFigure);
            app.EnterLabel_3.Text = 'Enter ';
            app.EnterLabel_4 = uilabel(app.UIFigure);
            app.EnterLabel_4.Text = 'Enter ';
            app.MEditFieldLabel = uilabel(app.UIFigure);
            app.MEditFieldLabel.Text = 'M = ';
            app.MEditField = uieditfield(app.UIFigure, 'numeric');
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
            app.NfortimemeanEditFieldLabel = uilabel(app.UIFigure);
            app.NfortimemeanEditFieldLabel.Text = 'N for time mean= ';
            app.NfortimemeanEditField = uieditfield(app.UIFigure, 'numeric');
            app.NfortimemeanEditField.ValueChangedFcn = createCallbackFcn(app, @NfortimemeanEditFieldValueChanged, true);
            app.iEditFieldLabel = uilabel(app.UIFigure);
            app.iEditFieldLabel.Text = 'i = ';
            app.iEditField = uieditfield(app.UIFigure, 'numeric');
            app.iEditField.ValueChangedFcn = createCallbackFcn(app, @iEditFieldValueChanged, true);
            app.jEditFieldLabel = uilabel(app.UIFigure);
            app.jEditFieldLabel.Text = 'j = ';
            app.jEditField = uieditfield(app.UIFigure, 'numeric');
            app.jEditField.ValueChangedFcn = createCallbackFcn(app, @jEditFieldValueChanged, true);
            app.PlotMsamplesButton = uibutton(app.UIFigure, 'push');
            app.PlotMsamplesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMsamplesButtonPushed, true);
            app.PlotMsamplesButton.Text = 'Plot M samples';
            app.CalcPlotensemblemeanButton = uibutton(app.UIFigure, 'push');
            app.CalcPlotensemblemeanButton.ButtonPushedFcn = createCallbackFcn(app, @CalcPlotensemblemeanButtonPushed, true);
            app.CalcPlotensemblemeanButton.Text = 'Calc / Plot ensemble mean';
            app.CalctimemeanofNButton = uibutton(app.UIFigure, 'push');
            app.CalctimemeanofNButton.ButtonPushedFcn = createCallbackFcn(app, @CalctimemeanofNButtonPushed, true);
            app.CalctimemeanofNButton.Text = 'Calc  time mean of N';
            app.CalcACFofijButton = uibutton(app.UIFigure, 'push');
            app.CalcACFofijButton.ButtonPushedFcn = createCallbackFcn(app, @CalcACFofijButtonPushed, true);
            app.CalcACFofijButton.Text = 'Calc ACF of i & j';
            app.CalctimeACFofNButton = uibutton(app.UIFigure, 'push');
            app.CalctimeACFofNButton.ButtonPushedFcn = createCallbackFcn(app, @CalctimeACFofNButtonPushed, true);
            app.CalctimeACFofNButton.Text = 'Calc time ACF of N';
            app.CalcPlotPSDButton = uibutton(app.UIFigure, 'push');
            app.CalcPlotPSDButton.ButtonPushedFcn = createCallbackFcn(app, @CalcPlotPSDButtonPushed, true);
            app.CalcPlotPSDButton.Text = 'Calc / Plot PSD';
            app.CalctotalaveragepowerButton = uibutton(app.UIFigure, 'push');
            app.CalctotalaveragepowerButton.ButtonPushedFcn = createCallbackFcn(app, @CalctotalaveragepowerButtonPushed, true);
            app.CalctotalaveragepowerButton.Text = 'Calc total average power';
            app.ProjectofPROBABILITYSTOCHASTICPROCESSESLabel = uilabel(app.UIFigure);
            app.ProjectofPROBABILITYSTOCHASTICPROCESSESLabel.Text = 'Project of PROBABILITY & STOCHASTIC PROCESSES';
            app.NfortimeACFEditFieldLabel = uilabel(app.UIFigure);
            app.NfortimeACFEditFieldLabel.Text = 'N for timeACF= ';
            app.NfortimeACFEditField = uieditfield(app.UIFigure, 'numeric');
            app.NfortimeACFEditField.ValueChangedFcn = createCallbackFcn(app, @NfortimeACFEditFieldValueChanged, true);
            app.EnterLabel_5 = uilabel(app.UIFigure);
            app.EnterLabel_5.Text = 'Enter ';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.PresstoimportXtButton = uibutton(app.UIFigure, 'push');
            app.PresstoimportXtButton.ButtonPushedFcn = createCallbackFcn(app, @PresstoimportXtButtonPushed, true);
            app.PresstoimportXtButton.Text = 'Press to import X(t)';
            app.PresstoimporttimevectorButton = uibutton(app.UIFigure, 'push');
            app.PresstoimporttimevectorButton.ButtonPushedFcn = createCallbackFcn(app, @PresstoimporttimevectorButtonPushed, true);
            app.PresstoimporttimevectorButton.Text = 'Press to import time vector';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.UITable3.CellEditCallback = createCallbackFcn(app, @UITable3CellEdit, true);
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.UITable4 = uitable(app.UIFigure);
            app.UITable4.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable4.RowName = {};
            app.UITable4.CellEditCallback = createCallbackFcn(app, @UITable4CellEdit, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.UITable5 = uitable(app.UIFigure);
            app.UITable5.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable5.RowName = {};
            app.UITable5.CellEditCallback = createCallbackFcn(app, @UITable5CellEdit, true);
            app.importXtCosfunctionwithitstimevectorButton = uibutton(app.UIFigure, 'push');
            app.importXtCosfunctionwithitstimevectorButton.ButtonPushedFcn = createCallbackFcn(app, @importXtCosfunctionwithitstimevectorButtonPushed, true);
            app.importXtCosfunctionwithitstimevectorButton.Text = 'import  X(t) Cos function with its time vector';
            app.PlotEnsembleACFButton = uibutton(app.UIFigure, 'push');
            app.PlotEnsembleACFButton.ButtonPushedFcn = createCallbackFcn(app, @PlotEnsembleACFButtonPushed, true);
            app.PlotEnsembleACFButton.Text = ' Plot  Ensemble ACF';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.TbEditFieldLabel = uilabel(app.UIFigure);
            app.TbEditFieldLabel.Text = 'Tb';
            app.TbEditField = uieditfield(app.UIFigure, 'numeric');
            app.TbEditField.ValueChangedFcn = createCallbackFcn(app, @TbEditFieldValueChanged, true);
            app.EnterLabel_6 = uilabel(app.UIFigure);
            app.EnterLabel_6.Text = 'Enter ';
            app.importXtofUNIPOLARCosfunctionwithitstimevectorButton = uibutton(app.UIFigure, 'push');
            app.importXtofUNIPOLARCosfunctionwithitstimevectorButton.ButtonPushedFcn = createCallbackFcn(app, @importXtofUNIPOLARCosfunctionwithitstimevectorButtonPushed, true);
            app.importXtofUNIPOLARCosfunctionwithitstimevectorButton.Text = 'import  X(t) of UNIPOLAR Cos function with its time vector';
            app.noteimportthefunctionwitheachnewTbLabel = uilabel(app.UIFigure);
            app.noteimportthefunctionwitheachnewTbLabel.Text = 'note: import the function with each new Tb ';
            app.importXtofPOLARCosfunctionwithitstimevectorButton = uibutton(app.UIFigure, 'push');
            app.importXtofPOLARCosfunctionwithitstimevectorButton.ButtonPushedFcn = createCallbackFcn(app, @importXtofPOLARCosfunctionwithitstimevectorButtonPushed, true);
            app.importXtofPOLARCosfunctionwithitstimevectorButton.Text = 'import  X(t) of POLAR Cos function with its time vector';
            app.importXtofManchesterCosfunctionwithitstimevectorButton = uibutton(app.UIFigure, 'push');
            app.importXtofManchesterCosfunctionwithitstimevectorButton.ButtonPushedFcn = createCallbackFcn(app, @importXtofManchesterCosfunctionwithitstimevectorButtonPushed, true);
            app.importXtofManchesterCosfunctionwithitstimevectorButton.Text = 'import  X(t) of ’Manchester Cos function with its time vector';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'if u wanna add new matrices please write tha matirx in an excelfile';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'with name "cos.xlsx" and its time vector in the same file but sheet 2';
            app.pleasefillallneededvaluesbeforestartingLabel = uilabel(app.UIFigure);
            app.pleasefillallneededvaluesbeforestartingLabel.Text = 'please fill all needed values before starting';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end